classdef myequalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure         % UI Figure
        LabelSlider matlab.ui.control.Label  % 100Hz
        Slider1     matlab.ui.control.Slider % [-10 10]
        Label       matlab.ui.control.Label  % 300Hz
        Slider2     matlab.ui.control.Slider % [-10 10]
        Label2      matlab.ui.control.Label  % 600Hz
        Slider3     matlab.ui.control.Slider % [-10 10]
        Label3      matlab.ui.control.Label  % 1.2kHz
        Slider4     matlab.ui.control.Slider % [-10 10]
        Label4      matlab.ui.control.Label  % 2.4kHz
        Slider5     matlab.ui.control.Slider % [-10 10]
        Label5      matlab.ui.control.Label  % 4.8kHz
        Slider6     matlab.ui.control.Slider % [-10 10]
        Label6      matlab.ui.control.Label  % 9.35kHz
        Slider7     matlab.ui.control.Slider % [-10 10]
        Label7      matlab.ui.control.Label  % 17.15kHz
        Slider8     matlab.ui.control.Slider % [-10 10]
        btn_plot    matlab.ui.control.Button % Plot
        Label8      matlab.ui.control.Label  % 0
        Label9      matlab.ui.control.Label  % 0
        Label10     matlab.ui.control.Label  % 0
        Label11     matlab.ui.control.Label  % 0
        Label12     matlab.ui.control.Label  % 0
        Label13     matlab.ui.control.Label  % 0
        Label14     matlab.ui.control.Label  % 0
        Label15     matlab.ui.control.Label  % 0
        btn_load    matlab.ui.control.Button % Load
        Label16     matlab.ui.control.Label  % File Name
        Label17     matlab.ui.control.Label  % File Path
        btn_play    matlab.ui.control.Button % Play
        btn_stop    matlab.ui.control.Button % Stop
    end


    properties (Access = private)
        bandGain = [zeros(1,8)] % Description
        fileName % Description
        filePath % Description
    end

    methods (Access = private)

        function b = filtering(app)
            Fsamp = 44100;
            fpts = [0 199 200 399 400 799 800 1599 1600 3199 3200 6399 6400 12299 12300 21999 22000 Fsamp/2]/(Fsamp/2); 
            v = 10.^(app.bandGain/20);
            mval = [v(1) v(1) v(2) v(2) v(3) v(3) v(4) v(4) v(5) v(5) v(6) v(6) v(7) v(7) v(8) v(8) 0 0];
            N = 511;
            b = fir2(N, fpts, mval, chebwin(N+1));
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Slider1 value changed function
        function Slider1ValueChanged(app)
            value = round(app.Slider1.Value,1);
            app.Label8.Text = num2str(value, 2);
            app.bandGain(1) = value;
        end

        % Slider2 value changed function
        function Slider2ValueChanged(app)
            value = round(app.Slider2.Value,1);
            app.Label9.Text = num2str(value,2);
            app.bandGain(2) = value;
        end

        % Slider3 value changed function
        function Slider3ValueChanged(app)
            value = round(app.Slider3.Value,1);
            app.Label10.Text = num2str(value,2);
            app.bandGain(3) = value;
        end

        % Slider4 value changed function
        function Slider4ValueChanged(app)
            value = round(app.Slider4.Value,1);
            app.Label11.Text = num2str(value,2);
            app.bandGain(4) = value;
        end

        % Slider5 value changed function
        function Slider5ValueChanged(app)
            value = round(app.Slider5.Value,1);
            app.Label12.Text = num2str(value,2);
            app.bandGain(5) = value;
        end

        % Slider6 value changed function
        function Slider6ValueChanged(app)
            value = round(app.Slider6.Value,1);
            app.Label13.Text = num2str(value,2);
            app.bandGain(6) = value;
        end

        % Slider7 value changed function
        function Slider7ValueChanged(app)
            value = round(app.Slider7.Value,1);
            app.Label14.Text = num2str(value,2);
            app.bandGain(7) = value;
        end

        % Slider8 value changed function
        function Slider8ValueChanged(app)
            value = round(app.Slider8.Value,1);
            app.Label15.Text = num2str(value,2);
            app.bandGain(8) = value;
        end

        % btn_plot button pushed function
        function btn_plotButtonPushed(app)
            b = filtering(app);
            freqz(b);
        end

        % btn_load button pushed function
        function btn_loadButtonPushed(app)
            [app.fileName, app.filePath] = uigetfile('*.wav', 'Select input audio file(.wav)');
            try
            app.Label16.Text = app.fileName;
            app.Label17.Text = app.filePath;
            end
        end

        % btn_play button pushed function
        function btn_playButtonPushed(app)
            [x, Fs] = audioread(fullfile(app.filePath,app.fileName));
            b = filtering(app);
            y = filter(b, 1, x);
            sound(y, Fs);
        end

        % btn_stop button pushed function
        function btn_stopButtonPushed(app)
            clear sound;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = '100Hz';
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.Limits = [-10 10];
            app.Slider1.MajorTicks = [-10 -5 0 5 10];
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '300Hz';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-10 10];
            app.Slider2.MajorTicks = [-10 -5 0 5 10];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '600Hz';
            app.Slider3 = uislider(app.UIFigure);
            app.Slider3.Limits = [-10 10];
            app.Slider3.MajorTicks = [-10 -5 0 5 10];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged);
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = '1.2kHz';
            app.Slider4 = uislider(app.UIFigure);
            app.Slider4.Limits = [-10 10];
            app.Slider4.MajorTicks = [-10 -5 0 5 10];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanged);
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = '2.4kHz';
            app.Slider5 = uislider(app.UIFigure);
            app.Slider5.Limits = [-10 10];
            app.Slider5.MajorTicks = [-10 -5 0 5 10];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged);
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = '4.8kHz';
            app.Slider6 = uislider(app.UIFigure);
            app.Slider6.Limits = [-10 10];
            app.Slider6.MajorTicks = [-10 -5 0 5 10];
            app.Slider6.ValueChangedFcn = createCallbackFcn(app, @Slider6ValueChanged);
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = '9.35kHz';
            app.Slider7 = uislider(app.UIFigure);
            app.Slider7.Limits = [-10 10];
            app.Slider7.MajorTicks = [-10 -5 0 5 10];
            app.Slider7.ValueChangedFcn = createCallbackFcn(app, @Slider7ValueChanged);
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = '17.15kHz';
            app.Slider8 = uislider(app.UIFigure);
            app.Slider8.Limits = [-10 10];
            app.Slider8.MajorTicks = [-10 -5 0 5 10];
            app.Slider8.ValueChangedFcn = createCallbackFcn(app, @Slider8ValueChanged);
            app.btn_plot = uibutton(app.UIFigure, 'push');
            app.btn_plot.ButtonPushedFcn = createCallbackFcn(app, @btn_plotButtonPushed);
            app.btn_plot.Text = 'Plot';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = '0';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = '0';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = '0';
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = '0';
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = '0';
            app.Label13 = uilabel(app.UIFigure);
            app.Label13.Text = '0';
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Text = '0';
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Text = '0';
            app.btn_load = uibutton(app.UIFigure, 'push');
            app.btn_load.ButtonPushedFcn = createCallbackFcn(app, @btn_loadButtonPushed);
            app.btn_load.Text = 'Load';
            app.Label16 = uilabel(app.UIFigure);
            app.Label16.Text = 'File Name';
            app.Label17 = uilabel(app.UIFigure);
            app.Label17.Text = 'File Path';
            app.btn_play = uibutton(app.UIFigure, 'push');
            app.btn_play.ButtonPushedFcn = createCallbackFcn(app, @btn_playButtonPushed);
            app.btn_play.Text = 'Play';
            app.btn_stop = uibutton(app.UIFigure, 'push');
            app.btn_stop.ButtonPushedFcn = createCallbackFcn(app, @btn_stopButtonPushed);
            app.btn_stop.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = myequalizer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end